classdef GUIv11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UserInputPanel               matlab.ui.container.Panel
        XEditFieldLabel              matlab.ui.control.Label
        x                            matlab.ui.control.EditField
        YEditFieldLabel              matlab.ui.control.Label
        y                            matlab.ui.control.EditField
        RotationradEditFieldLabel    matlab.ui.control.Label
        rotation                     matlab.ui.control.EditField
        TimeEditFieldLabel           matlab.ui.control.Label
        time                         matlab.ui.control.EditField
        CurrentLocationPanel         matlab.ui.container.Panel
        XEditField_2Label            matlab.ui.control.Label
        x2                           matlab.ui.control.NumericEditField
        YEditField_2Label            matlab.ui.control.Label
        y2                           matlab.ui.control.NumericEditField
        RotationradEditField_2Label  matlab.ui.control.Label
        rotation2                    matlab.ui.control.NumericEditField
        TimeLabel_2                  matlab.ui.control.Label
        time2                        matlab.ui.control.NumericEditField
        IMUsensorPanel               matlab.ui.container.Panel
        AxLabel                      matlab.ui.control.Label
        Ax                           matlab.ui.control.NumericEditField
        AyLabel                      matlab.ui.control.Label
        Ay                           matlab.ui.control.NumericEditField
        VzLabel                      matlab.ui.control.Label
        Vz                           matlab.ui.control.NumericEditField
        AccelerationLabel            matlab.ui.control.Label
        AngularVelocityLabel         matlab.ui.control.Label
        XZsensorPanel                matlab.ui.container.Panel
        XLabel                       matlab.ui.control.Label
        xsensor                      matlab.ui.control.NumericEditField
        ZLabel                       matlab.ui.control.Label
        zsensor                      matlab.ui.control.NumericEditField
        rovercontrolswitchLabel      matlab.ui.control.Label
        screen                       matlab.ui.control.Switch
        CameraSwitchLabel            matlab.ui.control.Label
        cam                          matlab.ui.control.Switch
        start                        matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        raspiAddress
        raspiID
        raspiPassword
        mypi
        z_gyro_out
        x_acce_out
        y_acce_out
        x_output
        z_output
        acce_gyro_sen
        gyro_sensitivity
        acce_sensitivity
        xz
        mycam
        client
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % connection to touch screen
            app.client = tcpip('129.127.29.125', 50000);
            fopen(app.client);
            
            %             while 1
            %                 pause(1);
            %                 fread(app.client, [1, app.client.BytesAvailable]);
            %                 if (vs() ~= 0)
            %                     if (char(vs()) )
            %             end
            % connection to raspberry
            %             app.raspiAddress = '129.127.29.227';
            %             app.raspiID = 'pi';
            %             app.raspiPassword = 'raspberry';
            
            %             app.mypi = raspi(app.raspiAddress, app.raspiID, app.raspiPassword);
            
            %             i2c_bus = 'i2c-1';
            
            %             app.gyro_sensitivity = 0.00875;
            %             app.acce_sensitivity = 0.000061;
            %           mypi.AvailableI2CBuses
            %[i2cAddresses] = scanI2CBus(mypi,i2c_bus);
            
            %             app.xz = i2cdev(app.mypi,i2c_bus,'0x10');
            %             app.acce_gyro_sen = i2cdev(app.mypi,i2c_bus,'0x6B'); % acce+gyro is 104
            
            % enable the gyroscope and acceleration sensors
            %             writeRegister(app.acce_gyro_sen, hex2dec('1 0'), bin2dec('10100000')); % gyro and acce enabling
            
            %             % setting cameraboard for camera
            %             resolution = '160x120';
            %             app.mycam = cameraboard(app.mypi, 'Resolution', resolution);
            
        end

        % Value changed function: screen
        function screenValueChanged(app, event)
            
            flag1 = 0;
            flag2 = 0;
            while 1
                
                pause(1)
                vs = fread(app.client, [1, app.client.BytesAvailable]);
                switch app.screen.Value
                    % screen/pi on -> host comp stops
                    case '1'
                        % user editability off
                        app.x.Editable = 'off';
                        app.y.Editable = 'off';
                        app.rotation.Editable = 'off';
                        app.time.Editable = 'off';
                        app.start.Enable = 'off';
                        flag1 = flag1 + 1;
                        if (flag1 == 1)
                            flag2 = 0;
                            fwrite(app.client, "Pi start");
                        end
                        
                        % getting user input values from pi
                        if (vs() ~= 0) %vs=value from screen
                            if (char(vs(6:10)) == 'start')
                                app.screen.Value = '0';
                            elseif (char(vs(1)) == 'R')
                                app.x.Value = char(vs(2:4));
                                app.y.Value = char(vs(6:8));
                                app.rotation.Value = char(vs(10:12));
                                app.time.Value = char(vs(14:16));
%                             elseif (char(vs(1:5)) == 'reset')
%                                 app.x.Value = "0";
%                                 app.y.Value = "0";
%                                 app.rotation.Value = "0";
%                                 app.time.Value = "0";
%                                 
%                                 app.time2.Value = '0';
%                                 
%                                 app.screen.Value = '1';
%                                 app.cam.Value = '0';
                            end
                        end
                        
                        % screen/pi off -> host comp starts
                    case '0'
                        % user editability on
                        app.x.Editable = 'on';
                        app.y.Editable= 'on';
                        app.rotation.Editable= 'on';
                        app.time.Editable= 'on';
                        app.start.Enable = 'on';
                        flag2 = flag2 + 1;
                        if (flag2 == 1)
                            flag1 = 0;
                            fwrite(app.client,'Pi stop');
                        end
                        
                        % getting user input values from pi
                        if (vs() ~= 0)
                            if (char(vs(6:9)) == 'stop')
                                app.screen.Value = '1';aa
%                             elseif (char(vs(1:5)) == 'reset')
%                                 app.x.Value = "0";
%                                 app.y.Value = "0";
%                                 app.rotation.Value = "0";
%                                 app.time.Value = "0";
%                                 
%                                 app.time2.Value = '0';
%                                 
%                                 app.screen.Value = '1';
%                                 app.cam.Value = '0';
                            end
                        end
                end
            end
            
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            
            
            %             app.mycam.Rotation = 180;
            
            % save user input into variables
            ux = app.x.Value;
            uy = app.y.Value;
            urotation = app.rotation.Value;
            utime = app.time.Value;
            
            vhc = sprintf('R%s %s %s %s',ux,uy,urotation,utime); %vhc = value from host computer
            
            % passing user data to raspberry pi
            fwrite(app.client, vhc);
            
            % current location of the rover
            tic
            if (toc<str2double(utime))
                % get real time data
                while (toc<str2double(utime))
                    pause(0.1);
                    
                    % read current location from pi screen
                    %                             app.x2.Value = fread(app.client, outx_box);
                    %                             app.y2.Value = fread(app.client, outy_box);
                    %                             app.rotation2.Value = fread(app.client, outr_box);
                    app.time2.Value = toc;
                    
                    % read XZ sensor data
                    %                     app.x_output = readRegister(app.xz, 8, 'uint8');
                    %                     app.z_output = readRegister(app.xz, 10, 'uint8');
                    %                     app.xsensor.Value = double(app.x_output)/255;
                    %                     app.zsensor.Value = double(app.z_output)/255;
                    
                    % read IMU sensor data (gyroscope and accelerometer)
                    % rotation in z direction
                    %                         OUT_Z_L_G = readRegister(app.acce_gyro_sen, hex2dec('1C'));
                    %                         OUT_Z_H_G = readRegister(app.acce_gyro_sen, hex2dec('1D'));
                    %                         z_gyro_unscale = typecast([OUT_Z_L_G, OUT_Z_H_G], 'int16');
                    %                         app.z_gyro_out = double(z_gyro_unscale)*app.gyro_sensitivity;
                    %                         app.Vz.Value = app.z_gyro_out;
                    
                    % acceleration in x direction
                    %                         OUT_X_L_XL = readRegister(app.acce_gyro_sen, hex2dec('28')); %acceleration
                    %                         OUT_X_H_XL = readRegister(app.acce_gyro_sen, hex2dec('29'));
                    %                         x_acce_unscale = typecast([OUT_X_L_XL, OUT_X_H_XL], 'int16');
                    %                         app.x_acce_out = double(x_acce_unscale)*app.acce_sensitivity;
                    %                         app.Ax.Value = app.x_acce_out;
                    
                    % acceleration in y direction
                    %                         OUT_Y_L_XL = readRegister(app.acce_gyro_sen, hex2dec('2A'));
                    %                         OUT_Y_H_XL = readRegister(app.acce_gyro_sen, hex2dec('2B'));
                    %                         y_acce_unscale = typecast([OUT_Y_L_XL, OUT_Y_H_XL], 'int16');
                    %                         app.y_acce_out = double(y_acce_unscale)*app.acce_sensitivity;
                    %                         app.Ay.Value = app.y_acce_out;
                    
                    % enable camera
                    if (app.cam.Value == '1')
                        img = snapshot(app.mycam);
                        imagesc(img);
                    end
                end
            else
                app.cam.Value = '0';
                app.time2.Value = str2double(utime);
                
                % resetting the values of sensors
                app.Ax.Value = 0;
                app.Ay.Value = 0;
                app.Vz.Value = 0;
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
%             
%             app.x.Value = "0";
%             app.y.Value = "0";
%             app.rotation.Value = "0";
%             app.time.Value = "0";
%             app.time2.Value = '0';
%             
%             app.screen.Value = '1';
%             app.cam.Value = '0';
%             
%             rst = sprintf('Reset'); %reset
%             fwrite(app.client, rst);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UserInputPanel = uipanel(app.UIFigure);
            app.UserInputPanel.Title = 'User Input';
            app.XEditFieldLabel = uilabel(app.UserInputPanel);
            app.XEditFieldLabel.Text = 'X';
            app.x = uieditfield(app.UserInputPanel, 'text');
            app.x.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.UserInputPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.y = uieditfield(app.UserInputPanel, 'text');
            app.y.Editable = 'off';
            app.RotationradEditFieldLabel = uilabel(app.UserInputPanel);
            app.RotationradEditFieldLabel.Text = 'Rotation (rad)';
            app.rotation = uieditfield(app.UserInputPanel, 'text');
            app.rotation.Editable = 'off';
            app.TimeEditFieldLabel = uilabel(app.UserInputPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.time = uieditfield(app.UserInputPanel, 'text');
            app.time.Editable = 'off';
            app.CurrentLocationPanel = uipanel(app.UIFigure);
            app.CurrentLocationPanel.Title = 'Current Location';
            app.XEditField_2Label = uilabel(app.CurrentLocationPanel);
            app.XEditField_2Label.Text = 'X';
            app.x2 = uieditfield(app.CurrentLocationPanel, 'numeric');
            app.x2.Editable = 'off';
            app.YEditField_2Label = uilabel(app.CurrentLocationPanel);
            app.YEditField_2Label.Text = 'Y';
            app.y2 = uieditfield(app.CurrentLocationPanel, 'numeric');
            app.y2.Editable = 'off';
            app.RotationradEditField_2Label = uilabel(app.CurrentLocationPanel);
            app.RotationradEditField_2Label.Text = 'Rotation (rad)';
            app.rotation2 = uieditfield(app.CurrentLocationPanel, 'numeric');
            app.rotation2.Limits = [0 360];
            app.rotation2.Editable = 'off';
            app.TimeLabel_2 = uilabel(app.CurrentLocationPanel);
            app.TimeLabel_2.Text = 'Time';
            app.time2 = uieditfield(app.CurrentLocationPanel, 'numeric');
            app.time2.ValueDisplayFormat = '%.0f';
            app.time2.Editable = 'off';
            app.IMUsensorPanel = uipanel(app.UIFigure);
            app.IMUsensorPanel.Title = 'IMU sensor';
            app.AxLabel = uilabel(app.IMUsensorPanel);
            app.AxLabel.Text = 'Ax';
            app.Ax = uieditfield(app.IMUsensorPanel, 'numeric');
            app.Ax.Editable = 'off';
            app.AyLabel = uilabel(app.IMUsensorPanel);
            app.AyLabel.Text = 'Ay';
            app.Ay = uieditfield(app.IMUsensorPanel, 'numeric');
            app.Ay.Editable = 'off';
            app.VzLabel = uilabel(app.IMUsensorPanel);
            app.VzLabel.Text = 'Vz';
            app.Vz = uieditfield(app.IMUsensorPanel, 'numeric');
            app.Vz.Editable = 'off';
            app.AccelerationLabel = uilabel(app.IMUsensorPanel);
            app.AccelerationLabel.Text = 'Acceleration (m/(s^2))';
            app.AngularVelocityLabel = uilabel(app.IMUsensorPanel);
            app.AngularVelocityLabel.Text = 'Angular Velocity (deg/s)';
            app.XZsensorPanel = uipanel(app.UIFigure);
            app.XZsensorPanel.Title = 'XZ sensor';
            app.XLabel = uilabel(app.XZsensorPanel);
            app.XLabel.Text = 'X';
            app.xsensor = uieditfield(app.XZsensorPanel, 'numeric');
            app.xsensor.Editable = 'off';
            app.ZLabel = uilabel(app.XZsensorPanel);
            app.ZLabel.Text = 'Z';
            app.zsensor = uieditfield(app.XZsensorPanel, 'numeric');
            app.zsensor.Editable = 'off';
            app.rovercontrolswitchLabel = uilabel(app.UIFigure);
            app.rovercontrolswitchLabel.Text = 'rover control switch';
            app.screen = uiswitch(app.UIFigure, 'slider');
            app.screen.Items = {'0', '1'};
            app.screen.ValueChangedFcn = createCallbackFcn(app, @screenValueChanged, true);
            app.screen.Value = '1';
            app.CameraSwitchLabel = uilabel(app.UIFigure);
            app.CameraSwitchLabel.Text = 'Camera';
            app.cam = uiswitch(app.UIFigure, 'slider');
            app.cam.Items = {'0', '1'};
            app.cam.Value = '0';
            app.start = uibutton(app.UIFigure, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Enable = 'off';
            app.start.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUIv11

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end